classdef coviddata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CovidDataVisualisationUIFigure  matlab.ui.Figure
        AverageofdaysSlider             matlab.ui.control.Slider
        AverageofdaysSliderLabel        matlab.ui.control.Label
        OptionButtonGroup               matlab.ui.container.ButtonGroup
        DailyButton                     matlab.ui.control.RadioButton
        CumulativeButton                matlab.ui.control.RadioButton
        DatatoPlotButtonGroup           matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.RadioButton
        DeathsButton                    matlab.ui.control.RadioButton
        CasesButton                     matlab.ui.control.RadioButton
        StateorRegionListBox            matlab.ui.control.ListBox
        StateorRegionLabel              matlab.ui.control.Label
        CountryListBox                  matlab.ui.control.ListBox
        CountryLabel                    matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        Records             % Each Record holds all the country/state data
        
        CountriesListSize   
        CountriesList       % A list of available countries names
        SelectedCountry     % The currently selected country
        
        CurrentRegionsStatesList    % A list with the current states
        SelectedRegionState         % The currently selected region or state
        
        CasesCumulativeData         % Data to plot
        CasesDailyData
        DeathsCumulativeData
        DeathsDailyData
        
        ShowCases           % Control variables for type of cases / deaths
        ShowDeaths
        ShowBoth
        
        ShowCumulative   % Control variables for cumulative / daily
        ShowDaily
        
        
        AverageOfDay    % Moving average value in slider
        ChartTitle      % Title of the chart
        
        Dates           % Formated timepoints in dates
        StartDate
        EndDate
        TimePoints
    end
    
    methods (Access = private)
        
        % Find and update the selected state/region
        function updateStatesRegions(app)

            cc=1;
            app.CurrentRegionsStatesList = cell(1,100);
            %current_name = app.SelectedCountry;
            for ii = 1:app.CountriesListSize

                if string(app.SelectedCountry) == string(app.Records{ii}.Country)

                    app.CurrentRegionsStatesList{cc} = app.Records{ii}.StateRegion;
                    cc = cc+ 1;
                end
            end

          app.CurrentRegionsStatesList=app.CurrentRegionsStatesList(~cellfun('isempty',app.CurrentRegionsStatesList));
          app.StateorRegionListBox.Items = app.CurrentRegionsStatesList;

        end
        
        % Helper function to find a Record by index
        function index = findRecord(app)       
            ii = 1;
            while ii <= app.CountriesListSize
                if (string(app.Records{ii}.Country) == string(app.SelectedCountry)) & (string(app.Records{ii}.StateRegion) == string(app.SelectedRegionState))
                   
                    index = ii;
                    break;
                end
                ii = ii + 1;
            end  
        end
        
        % Update the data to plot
        function updateData(app)
            index = app.findRecord();
            
            app.CasesDailyData = app.Records{index}.CasesDaily;
            app.CasesCumulativeData = app.Records{index}.CasesCumulative;
                
             app.DeathsDailyData = app.Records{index}.DeathsDaily;
             app.DeathsCumulativeData = app.Records{index}.DeathsCumulative;
            
             if (app.AverageOfDay > 1)
                app.CasesDailyData = movmean(app.CasesDailyData, app.AverageOfDay);
                app.CasesCumulativeData = movmean(app.CasesCumulativeData, app.AverageOfDay);
                
                app.DeathsDailyData = movmean(app.DeathsDailyData, app.AverageOfDay);
                app.DeathsCumulativeData = movmean(app.DeathsCumulativeData, app.AverageOfDay);
             end
            
            
        end
        
        % Plot the data
        function plotData(app)
            
            cla(app.UIAxes, 'reset');
            
            % Select Cumulative or Daily data
            if app.ShowCumulative   % Show cumulative
                d1 = app.CasesCumulativeData;
                d2 = app.DeathsCumulativeData;
                st1 = 'Cumulative';
            else                    % Show Daily               
                d1 = app.CasesDailyData;
                d2 = app.DeathsDailyData;
                st1 = 'Daily';
            end
            
            % Plot Cases, Deaths or Both
            if app.ShowCases
                
                bar(app.UIAxes,app.TimePoints,d1);
                st2 = 'Cases';
                app.UIAxes.YAxis.Exponent = 0;
                
            elseif app.ShowDeaths
                plot(app.UIAxes,app.TimePoints, d2, 'Color', 'red', 'LineWidth', 1.5);
                st2 = 'Deaths';
                app.UIAxes.YAxis.Exponent = 0;
                
            else
                % 1st plot
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes,app.TimePoints, d1);
                
                hold(app.UIAxes, 'on');
                
                % 2nd plot
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes,app.TimePoints, d2, 'Color', 'red', 'LineWidth', 1.5);
                
                
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
                
                hold(app.UIAxes, 'off');
                st2 = 'Cases and Deaths';
                 
            end
            
            % Format x axis to show dates
            datetick(app.UIAxes, 'x', 'mm/yy');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            xlabel(app.UIAxes, 'Dates');
           
            hold(app.UIAxes, 'off');
            
            % Create the title of the chart
            
            if isequal(app.SelectedCountry, 'Global')
                st3 = 'Globally';
            elseif isequal(app.SelectedRegionState, 'All')
                st3 = [ 'in ', app.SelectedCountry ];
            else
                st3 = [ 'in ', app.SelectedRegionState ];
            end
            
            t = sprintf("%s Number of %s %s",st1,st2,st3);
            title(app.UIAxes,t);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load data from mat file
            load('covid_data.mat', 'covid_data');
            
            % Get list of country names and populate the list box
            app.CountriesList = [{'Global'}; unique(covid_data(2:end,1))];
            app.CountryListBox.Items = app.CountriesList;
            
            % Parse data and create a list of Records for each
            % Country - Region State pair
            rows = size(covid_data,1);
            cols = size(covid_data,2) - 2;
            
            app.CountriesListSize = rows;
            records_list = cell(1,app.CountriesListSize);
            
            global_cases_cumulative = zeros(1,cols);
            global_cases_daily = zeros(1,cols);
            
            global_deaths_cumulative = zeros(1,cols);
            global_deaths_daily = zeros(1,cols);
                
            for ii = 2:rows
                row = covid_data(ii,:);
                name = row{1};
                
                if isempty(row{2})
                    stateRegion='All';
                else
                    stateRegion = row{2};
                end
                data = reshape(cell2mat(row(3:end)), 2, []);
                
                cases_cumulative = data(1,:);
                cases_daily = [cases_cumulative(1), diff(cases_cumulative)];
                cases_daily(cases_daily < 0) = 0;
                
                deaths_cumulative = data(2,:);
                deaths_daily = [deaths_cumulative(1), diff(deaths_cumulative)];
                deaths_daily(deaths_daily < 0) = 0;
                
                % Global data
                global_cases_cumulative = global_cases_cumulative + cases_cumulative;
                global_deaths_cumulative = global_deaths_cumulative + deaths_cumulative;
                
                records_list{ii} = Record(name,stateRegion,cases_cumulative,cases_daily,deaths_cumulative,deaths_daily);
                
            end
            
            global_cases_daily = [global_cases_cumulative(1), diff(global_cases_cumulative)];
            global_deaths_daily = [global_deaths_cumulative(1), diff(global_deaths_cumulative)];
            
            global_object = Record('Global','All',global_cases_cumulative,global_cases_daily,global_deaths_cumulative,global_deaths_daily);
            records_list{1} = global_object;
            
            % Create date timepoints
            app.Dates = covid_data(1,3:end);
            app.StartDate = datenum(app.Dates{1}, 'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end}, 'mm/dd/yy');
            app.TimePoints = linspace(app.StartDate , app.EndDate, length(app.Dates));
            
            
            % Cases and Deaths selector
            app.CasesButton.Value = true;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;

            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth = app.BothButton.Value;
            
            % Cumulative and Daily Selector
            app.CumulativeButton.Value = true;
            app.DailyButton.Value = false;
            
            app.ShowCumulative = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            
            
            app.Records = records_list;

            app.SelectedCountry = app.Records{1}.Country;
            app.CountryListBox.Value = app.SelectedCountry;
            
            app.AverageofdaysSlider.Value = 1;
            app.AverageOfDay = 1;
            
            app.updateStatesRegions();
            app.SelectedRegionState = app.StateorRegionListBox.Items{1};
            
            app.updateData();
            app.plotData();
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.SelectedCountry = app.CountryListBox.Value;
            app.SelectedRegionState = app.StateorRegionListBox.Items{1};
            
            app.updateStatesRegions();
            app.updateData();
            app.plotData();
            

        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.SelectedRegionState = app.StateorRegionListBox.Value;
            
            app.updateStatesRegions();
            app.updateData();
            app.plotData();


        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            selectedButton = app.OptionButtonGroup.SelectedObject;
%             if selectedButton.Text == "Daily"
%                 app.ShowDaily = true;
%             else
%                 app.ShowDaily = false;
%             end
            
            app.ShowDaily = app.DailyButton.Value;
            app.ShowCumulative = app.CumulativeButton.Value;
            
            app.plotData();
            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            % selectedButton = app.DatatoPlotButtonGroup.SelectedObject;
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value ;
            app.ShowBoth = app.BothButton.Value;

            app.plotData();
        end

        % Value changed function: AverageofdaysSlider
        function AverageofdaysSliderValueChanged(app, event)
            app.AverageOfDay = app.AverageofdaysSlider.Value;
            app.updateData();
            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CovidDataVisualisationUIFigure = uifigure('Visible', 'off');
            app.CovidDataVisualisationUIFigure.Name = 'Covid Data Visualisation';
            app.CovidDataVisualisationUIFigure.Icon = 'dashboard.png';
            app.UIAxes = uiaxes(app.CovidDataVisualisationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryLabel = uilabel(app.CovidDataVisualisationUIFigure);
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.CovidDataVisualisationUIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.CovidDataVisualisationUIFigure);
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.CovidDataVisualisationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.DatatoPlotButtonGroup = uibuttongroup(app.CovidDataVisualisationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.CovidDataVisualisationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AverageofdaysSliderLabel = uilabel(app.CovidDataVisualisationUIFigure);
            app.AverageofdaysSliderLabel.Text = 'Average # of days';
            app.AverageofdaysSlider = uislider(app.CovidDataVisualisationUIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanged, true);
            app.AverageofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AverageofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.CovidDataVisualisationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = coviddata

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CovidDataVisualisationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CovidDataVisualisationUIFigure)
        end
    end
end